classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Timetostopcar500sLabel       matlab.ui.control.Label
        TimessccLabel                matlab.ui.control.Label
        NewVoiceCommandButton        matlab.ui.control.Button
        DistancetoFinishLabel        matlab.ui.control.Label
        LastVoiceCommandLabel        matlab.ui.control.Label
        initializeButton             matlab.ui.control.Button
        startpositionEditField       matlab.ui.control.EditField
        startpositionEditFieldLabel  matlab.ui.control.Label
        stoppositionEditField        matlab.ui.control.EditField
        stoppositionEditFieldLabel   matlab.ui.control.Label
        velocityGauge                matlab.ui.control.SemicircularGauge
        velocityGaugeLabel           matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        PositionLabel                matlab.ui.control.Label
        accelerationKnob             matlab.ui.control.Knob
        accelerationKnobLabel        matlab.ui.control.Label
        AccelerationLabel            matlab.ui.control.Label
        VelocityLabel                matlab.ui.control.Label
        KITTLamp                     matlab.ui.control.Lamp
        KITTLampLabel                matlab.ui.control.Label
        KITTSwitch                   matlab.ui.control.Switch
        KITTSwitchLabel              matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RandTimer          % Timer object
        PlotLine           % Line object
    end
    

    methods (Access = private)
    
        function RandTimerFcn(app,~,~)
            global g1
            delete(g1);
            %v = str2double(app.VelocityLabel.Text);
            global v
            global kitt
            global pos

            global a 

            kitt
            
            v = kitt.velocity;
            a_read = kitt.acceleration;
            xpos = -1*kitt.positionx + 600;
            status = kitt.status;
            %time = kitt.time;
            
            
                        %value = kitt.velocity;
                        app.velocityGauge.Value = v;
            value=sprintf('Velocity = %.2f',v);
            app.VelocityLabel.Text = (value);
            
            
                        %value = kitt.acceleration;
            value=sprintf('Acceleration = %.2f',a_read);
            app.AccelerationLabel.Text = (value);
            
            
                                    %value = kitt.positionx;
            value=sprintf('Position = %.2f',xpos);
            app.PositionLabel.Text = (value);
            
            value = kitt.status;
            if (value == -1)
            app.StatusLabel.Text = ('Status = crash occured');
            elseif (value == 1)
                app.StatusLabel.Text = ('Status = ON');
                kitt.acceleration = a;
            else
                app.StatusLabel.Text = ('Status = OFF');
            end
            
            dis2finish = -1*str2double(app.stoppositionEditField.Value) + xpos;
            app.DistancetoFinishLabel.Text = sprintf('Distance to Finish = %.2fcm',dis2finish);  

            %pos(end + 1) = ceil(xpos);
            
            plot(app.UIAxes,300,xpos,'b.')
            hold( app.UIAxes, 'on' )
            img = imread('car.png');             % Load a sample image 
            %img1 = imrotate(img,90);
            g1 = image(app.UIAxes,[280 320],[xpos-40 xpos+40],img);        % Plot the image
            
       end        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %rectangle(app.UIAxes,'Position',[1,2,5,10],'FaceColor',[0.8 0.8 0.8],'EdgeColor','k',...
    %'LineWidth',3);
            %axis(app.UIAxes, 'off');
            
            waypoint_1_x = 20;
            waypoint_1_y = 100;
            waypoint_2_x = 20;
            waypoint_2_y = 200;
            
            
            global kitt
            kitt = car;
            global v;
            v = 0;
            global a;
            a = 0;
            kitt.velocity = v;
            kitt.acceleration = a;
            global pos;
            pos = [0];
            
            global ti;
            ti = 0;
            
                app.KITTLamp.Color = 'red';
                        % Create timer object
            app.RandTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.1, ...                     % Period is 0.1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.RandTimerFcn);      % Specify callback function
            
           patch(app.UIAxes,'vertices', [0, 0; 0, 5; 600, 5; 600, 0], ...
          'faces', [1, 2, 3, 4], ...
          'FaceColor', 'b', ...
          'FaceAlpha', 0.5)
           
           patch(app.UIAxes,'vertices', [0, 600; 600, 600; 600, 595; 0, 595], ...
          'faces', [1, 2, 3, 4], ...
          'FaceColor', 'b', ...
          'FaceAlpha', 0.5)
           
            hold(app.UIAxes, 'on');
            %plot(app.UIAxes,waypoint_1_x,waypoint_1_y,'r+')
            %plot(app.UIAxes,waypoint_2_x,waypoint_2_y,'r+')
            %plot(app.UIAxes,0,0,'rx')
            %plot(app.UIAxes,0,600,'rx')
            %plot(app.UIAxes,600,0,'rx')
            %plot(app.UIAxes,600,600,'rx')
            yline(app.UIAxes,0,'-','Wall');
            yline(app.UIAxes,600,'-','Wall');
            xlim(app.UIAxes,[-30, 630])
            ylim(app.UIAxes,[-30, 630])
            app.UIAxes.YDir = 'reverse'


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.RandTimer);
            delete(app)
        end

        % Callback function
        function velocityKnobValueChanged(app, event)
            value = app.velocityKnob.Value;
            global v
            v = value;
            value=sprintf('Velocity = %.2f',value);
            app.VelocityLabel.Text = (value);
        end

        % Value changed function: KITTSwitch
        function KITTSwitchValueChanged(app, event)
            value = app.KITTSwitch.Value;
               global kitt
            if (strcmpi(value,'On') == 1)
                kitt.status = 1
                app.KITTLamp.Color = 'green';
            elseif (strcmpi(value,'Off') == 1)
                kitt.status = 0
                app.KITTLamp.Color = 'red';
            end
        end

        % Callback function
        function velocityKnobValueChanging(app, event)
            value = event.Value;

            global v
            v = value;
            value=sprintf('Velocity = %.2f',value);
            app.VelocityLabel.Text = (value);
        end

        % Value changing function: accelerationKnob
        function accelerationKnobValueChanging(app, event)
            value = event.Value;
            global a
            a = value;
        end

        % Callback function
        function crashButtonPushed(app, event)
            global kitt
            kitt.status = 1;
        end

        % Value changing function: startpositionEditField
        function startpositionEditFieldValueChanging(app, event)
            changingValue = event.Value;

        end

        % Button pushed function: initializeButton
        function initializeButtonPushed(app, event)
            global kitt;
            start_value = str2double(app.startpositionEditField.Value);
            stop_value = str2double(app.stoppositionEditField.Value);
            yline(app.UIAxes,start_value,'--','Start Position');
            hold(app.UIAxes, 'on' )
            yline(app.UIAxes,stop_value,'-.','Stop position');
            app.initializeButton.Enable = 'off';
            kitt.positionx = -1*start_value + 600;
            start(app.RandTimer);
        end

        % Button pushed function: NewVoiceCommandButton
        function NewVoiceCommandButtonPushed(app, event)
            lastcommand = 'Go Left';
            app.LastVoiceCommandLabel.Text = append('Last Voice Command: ',lastcommand);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Position of KITT')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.KITTSwitchLabel = uilabel(app.UIFigure);
            app.KITTSwitchLabel.Text = 'KITT';
            app.KITTSwitch = uiswitch(app.UIFigure, 'slider');
            app.KITTSwitch.ValueChangedFcn = createCallbackFcn(app, @KITTSwitchValueChanged, true);
            app.KITTLampLabel = uilabel(app.UIFigure);
            app.KITTLampLabel.Text = 'KITT';
            app.KITTLamp = uilamp(app.UIFigure);
            app.VelocityLabel = uilabel(app.UIFigure);
            app.VelocityLabel.Text = 'Velocity = ';
            app.AccelerationLabel = uilabel(app.UIFigure);
            app.AccelerationLabel.Text = 'Acceleration = ';
            app.accelerationKnobLabel = uilabel(app.UIFigure);
            app.accelerationKnobLabel.Text = 'acceleration';
            app.accelerationKnob = uiknob(app.UIFigure, 'continuous');
            app.accelerationKnob.Limits = [-15 15];
            app.accelerationKnob.ValueChangingFcn = createCallbackFcn(app, @accelerationKnobValueChanging, true);
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position = ';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status = ';
            app.velocityGaugeLabel = uilabel(app.UIFigure);
            app.velocityGaugeLabel.Text = 'velocity';
            app.velocityGauge = uigauge(app.UIFigure, 'semicircular');
            app.velocityGauge.Limits = [-50 50];
            app.stoppositionEditFieldLabel = uilabel(app.UIFigure);
            app.stoppositionEditFieldLabel.Text = 'stop position';
            app.stoppositionEditField = uieditfield(app.UIFigure, 'text');
            app.startpositionEditFieldLabel = uilabel(app.UIFigure);
            app.startpositionEditFieldLabel.Text = 'start position';
            app.startpositionEditField = uieditfield(app.UIFigure, 'text');
            app.startpositionEditField.ValueChangingFcn = createCallbackFcn(app, @startpositionEditFieldValueChanging, true);
            app.initializeButton = uibutton(app.UIFigure, 'push');
            app.initializeButton.ButtonPushedFcn = createCallbackFcn(app, @initializeButtonPushed, true);
            app.initializeButton.Text = 'initialize';
            app.LastVoiceCommandLabel = uilabel(app.UIFigure);
            app.LastVoiceCommandLabel.Text = 'Last Voice Command: ';
            app.DistancetoFinishLabel = uilabel(app.UIFigure);
            app.DistancetoFinishLabel.Text = 'Distance to Finish: ';
            app.NewVoiceCommandButton = uibutton(app.UIFigure, 'push');
            app.NewVoiceCommandButton.ButtonPushedFcn = createCallbackFcn(app, @NewVoiceCommandButtonPushed, true);
            app.NewVoiceCommandButton.Text = 'New Voice Command';
            app.TimessccLabel = uilabel(app.UIFigure);
            app.TimessccLabel.Text = 'Time: ss.cc';
            app.Timetostopcar500sLabel = uilabel(app.UIFigure);
            app.Timetostopcar500sLabel.Text = 'Time to stop car: 5.00 s';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end